classdef Foreground_Segmentator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ComputerVisionChallengeG21UIFigure  matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
        InputParametersPanel           matlab.ui.container.Panel
        SceneFolderButton              matlab.ui.control.Button
        selectedscenefolderpathLabel   matlab.ui.control.Label
        VirtualBackgroundButton        matlab.ui.control.Button
        selectedvirtualbackgroundpathLabel  matlab.ui.control.Label
        SelectModeDropDownLabel        matlab.ui.control.Label
        SelectModeDropDown             matlab.ui.control.DropDown
        StartButton                    matlab.ui.control.Button
        OutputFolderButton             matlab.ui.control.Button
        StartatimageEditFieldLabel     matlab.ui.control.Label
        StartatimageEditField          matlab.ui.control.NumericEditField
        StopButton                     matlab.ui.control.Button
        VideoNameLabel                 matlab.ui.control.Label
        VideoNameEditField             matlab.ui.control.EditField
        LoopVideoCheckBox              matlab.ui.control.CheckBox
        selectedoutputfolderpathLabel  matlab.ui.control.Label
        ShowRenderingCheckBox          matlab.ui.control.CheckBox
        LDropDownLabel                 matlab.ui.control.Label
        LDropDown                      matlab.ui.control.DropDown
        RDropDownLabel                 matlab.ui.control.Label
        RDropDown                      matlab.ui.control.DropDown
        OutputPanel                    matlab.ui.container.Panel
        FramesProcessedLabel           matlab.ui.control.Label
        LogTextAreaLabel               matlab.ui.control.Label
        LogTextArea                    matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        exitloop % Variables used for gui
        videosrc
        videoexported = 0;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SceneFolderButton
        function SceneFolderButtonPushed(app, event)
            
            src=uigetdir;                    % Calls 'uigetdir' to obtain the directory location from the user
            figure(app.ComputerVisionChallengeG21UIFigure);            % to show gui in foreground
            app.selectedscenefolderpathLabel.Text=src;  % Sets the label text to be the selected path
        end

        % Button pushed function: VirtualBackgroundButton
        function VirtualBackgroundButtonPushed(app, event)
            [bg, path1]= uigetfile({'*.jpg';'*.mp4';'*.avi'});                    % Calls 'uigetdir' to obtain the directory location from the user
            bgpath=strcat(path1,bg);
            figure(app.ComputerVisionChallengeG21UIFigure);               % to show gui in foreground
            app.selectedvirtualbackgroundpathLabel.Text=bgpath;  % Sets the label text to be the selected path

        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            %delete log to show the output on screen
            diary off
            if exist('log.txt', 'file')
                 delete('log.txt');
            end
            diary log.txt
            diary on
            %dont show warnings on the gui
            warning('off','images:bwfilt:tie');
            
            %configure variables
            app.exitloop=0;
            loop=0;
            v=1;
            addpath('bonus');
            store=0;
            i=0;
            % Start timer here
            tStart=tic;
            
            %take user input
            src=app.selectedscenefolderpathLabel.Text;
            start = app.StartatimageEditField.Value;
            render_mode=app.SelectModeDropDown.Value;
            bg=app.selectedvirtualbackgroundpathLabel.Text; 
            N = 7;
            if app.LDropDown.Value == "1"
                L = 1;
            elseif app.LDropDown.Value == "2"
                L = 2;
            end
            if app.RDropDown.Value == "2"
                R = 2;
            elseif app.RDropDown.Value == "3"
                R = 3;
            end
            %run imagereader function
            ir = ImageReader(src, L, R, start, N);

            %setup video
            if ~isempty(app.VideoNameEditField.Value) && ~isempty(app.videosrc) %only save video if name and path is given
                videofull = fullfile(app.videosrc, app.VideoNameEditField.Value);
                movie = VideoWriter(videofull, 'Motion JPEG AVI');
                movie.FrameRate = 10;
                store = 1;
            else 
                disp("Name or Path for video not given, no video created");
            end
            %setup render, check if background is in right format
            if render_mode == "substitute"
                if contains(bg, 'jpg') | contains(bg, 'png') %Check whether the background is an image or not
                    bg=imread(bg);
                    
                else
                    error('Background image is not .jpg or .png');
                end
            elseif render_mode =="bonus"
                if contains(bg, 'jpg') | contains(bg, 'png')
                    error('Background is in image. Should be a video');
                else
                    bg=VideoReader(bg);
                end
            end
            %consoleoutput at start of the program
            disp("Starting Calculations, Mode: " + render_mode);
            disp("Starting at Frame: " + start);


            %main loop where segmentation is executed
            while loop ~= 1
                
              % Get next image tensors
              [left, right, loop]=ir.next();
               
              % Generate binary mask
              [mask]=segmentation(left,right); %sumImage);
              
              % always the last frame of the tensor should masked
              frame=left(:,:,(end-2:end));
              frame2=right(:,:,(end-2:end));
              % Render new frame
              if render_mode=="bonus"
                  [result, v] = render_bonus( frame, mask, bg, render_mode, v);
              else
                  result = render(frame,mask,bg,render_mode);
              end
              
              %display the frames on the gui when requested
              if app.ShowRenderingCheckBox.Value == 1
                  %left
                  imshow(frame,'Parent',app.UIAxes);
                  %right
                  imshow(frame2,'Parent', app.UIAxes2);
                  %processed image
                  imshow(result,'Parent',app.UIAxes3);
              end
              %writes precessed image into movie data, when name and path
              %is given
              if store == 1
                  if ~isempty(result)
                        movie_frames=result;
                        open(movie)
                        writeVideo(movie, movie_frames);
                  else
                    break;
                  end
              end
              
              %if user input is stop, loop is aborted and video is given
              %out
              if app.exitloop == 1
                 break;
              end
              
              %print some information for the user
              app.FramesProcessedLabel.Text= "Frames Processed: " + num2str(i) ;
                      
              %to get console output onto the screen
              %diary log.txt
              temp = regexp(fileread('log.txt'), '\r?\n', 'split');
              app.LogTextArea.Value = temp;
                
              %forces update of visuals on gui
              drawnow;
              i = i+1;
              
              %if loop is about to end but user activated the loop
              %checkbox, the loop is again started from the start of the
              %folder
              if loop == 1
                  if app.LoopVideoCheckBox.Value == 1
                      loop = 0;
                      ir = ImageReader(src, L, R, 0, N);
                      disp("Starting again from Frame 0 due to Loop");
                  end
              end
           end
            
           %loop finished here
           disp("Calculations completed");
           %video saved and ready to export
           close(movie);
           %displays video on screen
           if store == 1
               disp("Video exported");
               app.videoexported = 1;
               videodisplay = strcat(videofull, '.avi');
               implay(videodisplay);
           end

           %display elapsed time
           tEnd = toc(tStart);
           disp("Elapsed time: "+ floor(tEnd/60) + " minutes and " + rem(tEnd,60) + " seconds");
        
           %to get console output onto the screen
           %diary log.txt
           temp = regexp(fileread('log.txt'), '\r?\n', 'split');
           app.LogTextArea.Value = temp;
           diary off
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.exitloop = 1;
        end

        % Button pushed function: OutputFolderButton
        function OutputFolderButtonPushed(app, event)
            app.videosrc = uigetdir;  
            figure(app.ComputerVisionChallengeG21UIFigure);            % to show gui in foreground
            app.selectedoutputfolderpathLabel.Text = app.videosrc;  % Sets the label text to be the selected path
        end

        % Value changed function: ShowRenderingCheckBox
        function ShowRenderingCheckBoxValueChanged(app, event)
            showrender = app.ShowRenderingCheckBox.Value;
            if showrender == 1
                disp("Displaying Images on screen, runtime increased");
                app.UIAxes.Visible = 'on';
                app.UIAxes2.Visible = 'on';
                app.UIAxes3.Visible = 'on';
                title(app.UIAxes, 'Original Image Left');
                title(app.UIAxes2, 'Original Image Right');
                title(app.UIAxes3, 'Processed Image');
            else
                %dont show images on screen anymore
                cla(app.UIAxes,'reset');
                cla(app.UIAxes2,'reset');
                cla(app.UIAxes3,'reset');
                disp("Displaying Images turned off");
                app.UIAxes.Visible = 'off';
                app.UIAxes2.Visible = 'off';
                app.UIAxes3.Visible = 'off';
            end         
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ComputerVisionChallengeG21UIFigure = uifigure('Visible', 'off');
            app.ComputerVisionChallengeG21UIFigure.Name = 'Computer Vision Challenge - G21';
            app.UIAxes = uiaxes(app.ComputerVisionChallengeG21UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Visible = 'off';
            app.UIAxes2 = uiaxes(app.ComputerVisionChallengeG21UIFigure);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.Visible = 'off';
            app.UIAxes3 = uiaxes(app.ComputerVisionChallengeG21UIFigure);
            title(app.UIAxes3, '')
            xlabel(app.UIAxes3, '')
            ylabel(app.UIAxes3, '')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.UIAxes3.Visible = 'off';
            app.InputParametersPanel = uipanel(app.ComputerVisionChallengeG21UIFigure);
            app.InputParametersPanel.Title = 'Input Parameters';
            app.SceneFolderButton = uibutton(app.InputParametersPanel, 'push');
            app.SceneFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SceneFolderButtonPushed, true);
            app.SceneFolderButton.Text = 'Scene Folder';
            app.selectedscenefolderpathLabel = uilabel(app.InputParametersPanel);
            app.selectedscenefolderpathLabel.Text = 'selected scene folder path';
            app.VirtualBackgroundButton = uibutton(app.InputParametersPanel, 'push');
            app.VirtualBackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @VirtualBackgroundButtonPushed, true);
            app.VirtualBackgroundButton.Text = 'Virtual Background';
            app.selectedvirtualbackgroundpathLabel = uilabel(app.InputParametersPanel);
            app.selectedvirtualbackgroundpathLabel.Text = 'selected virtual background path';
            app.SelectModeDropDownLabel = uilabel(app.InputParametersPanel);
            app.SelectModeDropDownLabel.Text = 'Select Mode';
            app.SelectModeDropDown = uidropdown(app.InputParametersPanel);
            app.SelectModeDropDown.Items = {'foreground', 'background', 'overlay', 'substitute', 'bonus'};
            app.SelectModeDropDown.Value = 'foreground';
            app.StartButton = uibutton(app.InputParametersPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.OutputFolderButton = uibutton(app.InputParametersPanel, 'push');
            app.OutputFolderButton.ButtonPushedFcn = createCallbackFcn(app, @OutputFolderButtonPushed, true);
            app.OutputFolderButton.Text = 'Output Folder';
            app.StartatimageEditFieldLabel = uilabel(app.InputParametersPanel);
            app.StartatimageEditFieldLabel.Text = 'Start at image';
            app.StartatimageEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.StopButton = uibutton(app.InputParametersPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.VideoNameLabel = uilabel(app.InputParametersPanel);
            app.VideoNameLabel.Text = 'Video Name';
            app.VideoNameEditField = uieditfield(app.InputParametersPanel, 'text');
            app.LoopVideoCheckBox = uicheckbox(app.InputParametersPanel);
            app.LoopVideoCheckBox.Text = 'Loop Video';
            app.selectedoutputfolderpathLabel = uilabel(app.InputParametersPanel);
            app.selectedoutputfolderpathLabel.Text = 'selected output folder path';
            app.ShowRenderingCheckBox = uicheckbox(app.InputParametersPanel);
            app.ShowRenderingCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowRenderingCheckBoxValueChanged, true);
            app.ShowRenderingCheckBox.Text = 'Show Rendering';
            app.LDropDownLabel = uilabel(app.InputParametersPanel);
            app.LDropDownLabel.Text = 'L';
            app.LDropDown = uidropdown(app.InputParametersPanel);
            app.LDropDown.Items = {'1', '2'};
            app.LDropDown.Value = '1';
            app.RDropDownLabel = uilabel(app.InputParametersPanel);
            app.RDropDownLabel.Text = 'R';
            app.RDropDown = uidropdown(app.InputParametersPanel);
            app.RDropDown.Items = {'2', '3'};
            app.RDropDown.Value = '2';
            app.OutputPanel = uipanel(app.ComputerVisionChallengeG21UIFigure);
            app.OutputPanel.Title = 'Output';
            app.FramesProcessedLabel = uilabel(app.OutputPanel);
            app.FramesProcessedLabel.Text = 'Frames Processed: ';
            app.LogTextAreaLabel = uilabel(app.OutputPanel);
            app.LogTextAreaLabel.Text = 'Log';
            app.LogTextArea = uitextarea(app.OutputPanel);
            % Show the figure after all components are created
            app.ComputerVisionChallengeG21UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Foreground_Segmentator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ComputerVisionChallengeG21UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ComputerVisionChallengeG21UIFigure)
        end
    end
end