classdef myGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
        InputParametersPanel          matlab.ui.container.Panel
        SceneFolderButton             matlab.ui.control.Button
        selectedscenefolderpathLabel  matlab.ui.control.Label
        VirtualBackgroundButton       matlab.ui.control.Button
        selectedvirtualbackgroundpathLabel  matlab.ui.control.Label
        SelectcamerasLabel            matlab.ui.control.Label
        EntervariablesLabel           matlab.ui.control.Label
        LEditFieldLabel               matlab.ui.control.Label
        LEditField                    matlab.ui.control.NumericEditField
        NEditFieldLabel               matlab.ui.control.Label
        NEditField                    matlab.ui.control.NumericEditField
        StartEditFieldLabel           matlab.ui.control.Label
        StartEditField                matlab.ui.control.NumericEditField
        REditFieldLabel               matlab.ui.control.Label
        REditField                    matlab.ui.control.NumericEditField
        SelectModeDropDownLabel       matlab.ui.control.Label
        SelectModeDropDown            matlab.ui.control.DropDown
        RUNButton                     matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SceneFolderButton
        function SceneFolderButtonPushed(app, event)
            
            src=uigetdir;                    % Calls 'uigetdir' to obtain the directory location from the user
            app.UIFigure.Visible = 'off';     % Toggles figure visible.  These two lines of code work-around an issue whether the figure is sent to the background.
            app.UIFigure.Visible = 'on';      
            app.selectedscenefolderpathLabel.Text=src;  % Sets the label text to be the selected path

           
        end

        % Button pushed function: VirtualBackgroundButton
        function VirtualBackgroundButtonPushed(app, event)
            [bg, path1]= uigetfile('*.jpg');                    % Calls 'uigetdir' to obtain the directory location from the user
            bgpath=strcat(path1,bg);
            app.UIFigure.Visible = 'off';     % Toggles figure visible.  These two lines of code work-around an issue whether the figure is sent to the background.
            app.UIFigure.Visible = 'on';      
            app.selectedvirtualbackgroundpathLabel.Text=bgpath;  % Sets the label text to be the selected path

        end

        % Value changed function: LEditField
        function LEditFieldValueChanged(app, event)
            L = app.LEditField.Value;
            app.UIFigure.Visible = 'off';     % Toggles figure visible.  These two lines of code work-around an issue whether the figure is sent to the background.
            app.UIFigure.Visible = 'on';      
            
            
        end

        % Value changed function: SelectModeDropDown
        function SelectModeDropDownValueChanged(app, event)
            mode = app.SelectModeDropDown.Value;
            
        end

        % Callback function
        function LcameraDropDownValueChanged(app, event)
            L_value = app.LcameraDropDown.Value;
            
        end

        % Callback function
        function RcameraDropDownValueChanged(app, event)


        end

        % Value changed function: NEditField
        function NEditFieldValueChanged(app, event)
            N = app.NEditField.Value;
            
        end

        % Value changed function: REditField
        function REditFieldValueChanged(app, event)
            R = app.LEditField.Value;
            app.UIFigure.Visible = 'off';     % Toggles figure visible.  These two lines of code work-around an issue whether the figure is sent to the background.
            app.UIFigure.Visible = 'on';    
            
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            loop=0;
            movie_frames=cell(1, 3000);
            N = app.NEditField.Value;
            src=app.selectedscenefolderpathLabel.Text;
            L = app.LEditField.Value;
            R = app.REditField.Value;
            start = app.StartEditField.Value;
            mode=app.SelectModeDropDown.Value;
            bg=app.selectedvirtualbackgroundpathLabel.Text; 
            obj=ImageReader(src, L, R, start, N);
            
            i=1;
            while loop ~= 1
                
                [left, right, l]=next(obj);
                loop=l;
                [mask, background]=segmentation(left,right);
                frame=left(:,:,(end-2:end));
                
                
                [result, result2,mask] = render(frame,mask,bg,mode);
                
                imshow(result2,'Parent',app.UIAxes);
                drawnow;
                imshow(mask,'Parent',app.UIAxes2);
                imshow(result,'Parent',app.UIAxes3);
                
                movie_frames{i}=result;
                i=i+1;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original sequence')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Mask')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Masked sequence')
            xlabel(app.UIAxes3, '')
            ylabel(app.UIAxes3, '')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.InputParametersPanel = uipanel(app.UIFigure);
            app.InputParametersPanel.Title = 'Input Parameters';
            app.SceneFolderButton = uibutton(app.InputParametersPanel, 'push');
            app.SceneFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SceneFolderButtonPushed, true);
            app.SceneFolderButton.Text = 'Scene Folder';
            app.selectedscenefolderpathLabel = uilabel(app.InputParametersPanel);
            app.selectedscenefolderpathLabel.Text = 'selected scene folder path';
            app.VirtualBackgroundButton = uibutton(app.InputParametersPanel, 'push');
            app.VirtualBackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @VirtualBackgroundButtonPushed, true);
            app.VirtualBackgroundButton.Text = 'Virtual Background';
            app.selectedvirtualbackgroundpathLabel = uilabel(app.InputParametersPanel);
            app.selectedvirtualbackgroundpathLabel.Text = 'selected virtual background path';
            app.SelectcamerasLabel = uilabel(app.InputParametersPanel);
            app.SelectcamerasLabel.Text = 'Select cameras';
            app.EntervariablesLabel = uilabel(app.InputParametersPanel);
            app.EntervariablesLabel.Text = 'Enter variables';
            app.LEditFieldLabel = uilabel(app.InputParametersPanel);
            app.LEditFieldLabel.Text = 'L';
            app.LEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.LEditField.ValueChangedFcn = createCallbackFcn(app, @LEditFieldValueChanged, true);
            app.NEditFieldLabel = uilabel(app.InputParametersPanel);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.NEditField.ValueChangedFcn = createCallbackFcn(app, @NEditFieldValueChanged, true);
            app.StartEditFieldLabel = uilabel(app.InputParametersPanel);
            app.StartEditFieldLabel.Text = 'Start';
            app.StartEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.REditFieldLabel = uilabel(app.InputParametersPanel);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.REditField.ValueChangedFcn = createCallbackFcn(app, @REditFieldValueChanged, true);
            app.SelectModeDropDownLabel = uilabel(app.InputParametersPanel);
            app.SelectModeDropDownLabel.Text = 'Select Mode';
            app.SelectModeDropDown = uidropdown(app.InputParametersPanel);
            app.SelectModeDropDown.Items = {'foreground', 'background', 'overlay', 'substitute'};
            app.SelectModeDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectModeDropDownValueChanged, true);
            app.SelectModeDropDown.Value = 'foreground';
            app.RUNButton = uibutton(app.InputParametersPanel, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = myGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end